classdef CalciSeg_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoaddataMenu                   matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        SaveasMenu                     matlab.ui.container.Menu
        Image                          matlab.ui.control.Image
        StackpreprocessingButtonGroup  matlab.ui.container.ButtonGroup
        orig_zDim                      matlab.ui.control.Label
        gauss_filt                     matlab.ui.control.Label
        curr_zDim                      matlab.ui.control.EditField
        zEditFieldLabel                matlab.ui.control.Label
        gauss_filt_std                 matlab.ui.control.EditField
        sdLabel                        matlab.ui.control.Label
        minROIsizeEditField            matlab.ui.control.EditField
        minROIsizeEditFieldLabel       matlab.ui.control.Label
        CalciSegLabel_3                matlab.ui.control.Label
        CalciSegLabel_2                matlab.ui.control.Label
        ProjectionmethodButtonGroup    matlab.ui.container.ButtonGroup
        maxButton                      matlab.ui.control.RadioButton
        meanButton                     matlab.ui.control.RadioButton
        stdButton_2                    matlab.ui.control.RadioButton
        RefinementiterationsEditField  matlab.ui.control.NumericEditField
        RefinementiterationsEditFieldLabel  matlab.ui.control.Label
        FilteringoflocalextremaButtonGroup  matlab.ui.container.ButtonGroup
        bothButton                     matlab.ui.control.RadioButton
        filteredButton                 matlab.ui.control.RadioButton
        rawButton                      matlab.ui.control.RadioButton
        StartsegmentingButton          matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        ax_22                          matlab.ui.control.UIAxes
        ax_12                          matlab.ui.control.UIAxes
        ax_21                          matlab.ui.control.UIAxes
        ax_11                          matlab.ui.control.UIAxes
    end


    properties (Access = public)
        stack_file = [];
        original_stack = [];
        stack = [];
        pca_stack = [];
        projection_method = 'std';
        filter_method = 'raw';
        n_rep = 0;
        min_ROI_size = [];
        locMax_y = [];
        locMax_x = [];
        pockets = [];
        pockets_labeled = [];
        summary_stats = [];
    end

    methods (Access = private)

        function [pockets_labeled, summary_stats] = CalciSeg(app, stack, projection_method, regmax_method, n_rep, minPixCount)
            % CalciSeg(stack, projection_method, regmax_method, n_rep, minPixCount) segments the spatial
            % aspects of a 3-dimensional stack (x*y*time) into individual regions
            % based on a refined 2D Delaunay triangulation.
            %
            % Input:
            %       stack             : 3-D matrix (x*y*time)
            %       projection_method : method for calculating the projection across time
            %                             - 'std'  - standard deviation projection
            %                             - 'mean' - mean projection
            %                             - 'max'  - max projection
            %       regmax_method     : method for determining how to identify local maxima%
            %                             - 'raw'      - simply apply imregionalmax on the projection
            %                             - 'filtered' - apply 2-D Gaussian filtering (sd=1) and imregionalmax on movmax-filtered projection
            %                             - 'both'     - combine both above-mentioned methods
            %       n_rep             : Number of iterations for refining the regions.
            %       minPixCount       : Minimum pixel area
            %
            % Output:
            %       pockets            : grayscale showing pocket distribution
            %       pockets_labeled    : pocket labels
            %       summary_stats      : avg, std, corr for each pocker
            %                    .pocket_Avg         : within-pocket average activity
            %                    .pocket_Corr_img    : average activity image (x*y)
            %                    .pocket_Std         : each pocket's std over time
            %                    .pocket_Std         : each pocket's std over time as image
            %                    .pocket_Corr        : within-pocket correlation
            %                    .pocket_Corr_img    : correlation image (x*y)
            %
            % Version: 26-Aug-23 (R2023a)

            % -------------------------------------------------------------------------

            % First, check whether the 3rd dimension of the stack (i.e. time) is larger
            % than 1
            if size(stack,3)==1
                projection_method = 'none';
                n_rep = 0;
            end

            % Get a reshaped version of the stack. Later, this will be used
            % to correlate pixels and pockets
            reshaped_stack = reshape(stack, [size(stack,1)*size(stack,2), size(stack,3)]);

            % -------------------------------------------------------------------------

            % Get projection from stack
            switch projection_method
                case 'std'
                    BF = nanstd(stack, [], 3);
                case 'mean'
                    BF = nanmean(stack, 3);
                case 'max'
                    BF = nanmax(stack, [], 3);
                case 'none'
                    BF = stack;
            end%switch
            % Substitute NaN with the minimum value
            BF(isnan(BF)) = min(BF(:));

            % -------------------------------------------------------------------------

            % Identify local extrema
            switch regmax_method
                case 'raw'
                    regmax_img = imregionalmax(BF,8)>0;
                    regmin_img = imregionalmin(BF,8)>0;
                case 'filtered'
                    % Filter to combine pockets that belong to the same large scale
                    % maximum/minimum
                    BF = imgaussfilt(BF, 1);
                    BF_std_max_filtered = movmax(movmax(BF',3)',3);
                    regmax_img = imregionalmax(BF_std_max_filtered,8)>0;
                    BF_std_min_filtered = movmin(movmin(BF',3)',3);
                    regmin_img = imregionalmin(BF_std_min_filtered,8)>0;
                case 'both'
                    % Filter to combine pockets that belong to the same large scale
                    % maximum/minimum
                    BF_std_max_filtered = movmax(movmax(BF',3)',3);
                    regmax_img = (imregionalmax(BF_std_max_filtered,8) + imregionalmax(BF,8))>0;
                    BF_std_min_filtered = movmin(movmin(BF',3)',3);
                    regmin_img = (imregionalmin(BF_std_min_filtered,8) + imregionalmin(BF,8))>0;
            end%switch
            ultimateErosion = bwulterode(regmax_img>0) + bwulterode(regmin_img>0);
            % Include points that are on the edge of the image. Late, this will set all
            % regions that are at the edge to zero
            ultimateErosion(1,:) = 1; ultimateErosion(:,1) = 1;
            ultimateErosion(end,:) = 1; ultimateErosion(:,end) = 1;
            [locMax.y, locMax.x] = find(ultimateErosion);

            % -------------------------------------------------------------------------

            % Segment image based on these maxima using a 2-D Delaunay
            % triangulation
            % Get linear indices of local maxima
            linearIndices = find(ultimateErosion);
            % Convert linear indices to (x, y, z) coordinates
            [Cx, Cy] = ind2sub(size(ultimateErosion), linearIndices);
            % Combine coordinates into Nx3 matrix
            C = [Cx, Cy]; clear Cx Cy

            % Segment image based on these maxima using a 3-D Delaunay
            % triangulation (i.e. assign pixels to the closest extrem point
            % Convert linear indices to (x, y, z) coordinates
            linearIndices = 1:numel(BF);
            [x, y] = ind2sub(size(BF), linearIndices);
            % Combine all and adjust aspect ratio;
            allVoxels = [x(:), y(:)];
            clear x y z linearIndices

            % Use knnsearch to find the index of the closest point in 'C' for each voxel
            pockets_labeled = knnsearch(C, allVoxels);
            % Reshape the resulting index array to the dimensions of the 3D volume
            pockets_labeled = reshape(pockets_labeled, size(BF));

            % Remove border
            ultimateErosion(1,:) = 0; ultimateErosion(:,1) = 0;
            ultimateErosion(end,:) = 0; ultimateErosion(:,end) = 0;
            linearIndices = find(ultimateErosion);
            % Create a logical mask
            mask = ismember(pockets_labeled, pockets_labeled(linearIndices));
            pockets_labeled(~mask) = 0;

            % -------------------------------------------------------------------------

            % Refine edges. Check first whether the 3rd dimension of the stack (i.e.
            % time) is larger than 1
            if ~size(stack,3)>1
                n_rep = 0;
            end
            % Get mask for neighbors
            nMask = [0 1 0; 1 1 1; 0 1 0];
            % Refine pockets
            hWait = waitbar(0, ['Please wait ... (0/', num2str(n_rep)]);
            for iRep = 1:n_rep
                waitbar(iRep/n_rep, hWait, ['Please wait ... (', num2str(iRep), '/', num2str(n_rep), ')']);
                % Keep track of changes
                previous_pockets_labeled = pockets_labeled;
                % Get avg signal trace per pocket
                pocketList = unique(pockets_labeled);
                pocketTC = nan(length(pocketList), size(stack,3));
                for iPocket = 1:length(pocketList)
                    idx = find(pockets_labeled==pocketList(iPocket));
                    pocketTC(iPocket, :) = nanmean(reshaped_stack(idx,:));
                end%iPocket
                clear iC iR iPocket
                % Get pixels in border regions
                candidates = imgradient(pockets_labeled, 'central')>0;
                idx_candidates = find(candidates);
                idx_table = reshape(1:numel(pockets_labeled), size(pockets_labeled));
                % Iterate over all pixels in border regions and check
                % whether thez have to be re-assigned.
                idx_candidates_newIdentiy = refinement_parfor(app, idx_candidates, idx_table, pockets_labeled, reshaped_stack, pocketTC, pocketList, nMask);
                % Update changed identities
                for iCan = 1:length(idx_candidates)
                    pockets_labeled(idx_candidates(iCan)) = idx_candidates_newIdentiy(iCan);
                end%iCan
                % Account for splitting of pockets. For this, iterate over
                % all pocket and check whether the corresponding itentity
                % can be found in more then one coherent regions
                unique_pockets = unique(pockets_labeled(:));
                for iPocket = 1:length(unique_pockets)
                    % Neglect identity of zero
                    % Get b/w image and identify number of regions
                    bw = pockets_labeled==unique_pockets(iPocket);
                    L = bwlabel(bw,4);
                    % If there is more than one region (i.e. more than
                    % the labels 0 and 1, assign new identities
                    unique_L = unique(L(:));
                    if length(unique_L)>2
                        % Iterate over all splitter
                        for iSplitter = 3:length(unique_L)
                            idx = find(L == unique_L(iSplitter));
                            pockets_labeled(idx) = max(unique_pockets)+1;
                            unique_pockets = [unique_pockets(:); max(unique_pockets)+1];
                        end%iSplitter
                    end% if more
                end%iPocket
                % Stop if no changes
                if sum(previous_pockets_labeled(:) == pockets_labeled(:)) == numel(pockets_labeled(:))
                    break
                end% if change
            end%iRep
            close(hWait)

            % -------------------------------------------------------------------------

            % Now, kick out pockets that are too small. For this, iterate
            % over all pockets, and check whether it is large enough. If
            % not, assign each pixel to the best neigbour.
            % Repeat as long as all small pockets are gone.
            pocketList = unique(pockets_labeled);
            [~,~,C] = unique(pockets_labeled);
            C = histcounts(C, 1:length(pocketList)+1);
            if any(C<=minPixCount)
                didCorrect = 0;
                while true
                    % --- Get avg signal trace per pocket
                    pocketList = unique(pockets_labeled);
                    pocketTC = nan(length(pocketList), size(stack,3));

                    for iPocket = 1:length(pocketList)
                        idx = find(pockets_labeled==pocketList(iPocket));
                        pocketTC(iPocket, :) = nanmean(reshaped_stack(idx,:));
                    end%iPocket
                    clear iC iR iPocket
                    % --- Get tabel with correct indices
                    idx_table = reshape(1:numel(pockets_labeled), size(pockets_labeled));
                    % --- Get unique pockets
                    unique_pockets = unique(pockets_labeled(:));
                    for iPocket = 1:length(unique_pockets)
                        % Neglect identity of zero
                        if unique_pockets(iPocket)~=0
                            % Get b/w image to identify number of pixels
                            bw = pockets_labeled==unique_pockets(iPocket);
                            if sum(bw(:))<minPixCount
                                % Get indices
                                idx_candidates = find(bw);
                                % Get current identity
                                currC = unique_pockets(iPocket);
                                % Iterate over all pixels
                                for iPx = 1:length(idx_candidates)
                                    % Get indices of neighbors
                                    idx_neighbor = conv2( double(idx_table==idx_candidates(iPx)), nMask, 'same')==1;
                                    % Get neighborhood clusters
                                    nC = unique(pockets_labeled(idx_neighbor));
                                    % Kick out current cluster
                                    nC(nC==currC) = [];
                                    if ~isempty(nC)
                                        nC_cut = nC;
                                        % Check whether neighbors are large enough
                                        for iN = 1:length(nC)
                                            bw = pockets_labeled==nC(iN);
                                            if sum(bw(:))+1<minPixCount
                                                nC_cut(nC_cut==nC(iN)) = [];
                                            end%if neighbor would be too small
                                        end%iN
                                    end%if isempty(nC)
                                    nC = nC_cut;
                                    % Assign identity of best fitting neighbor
                                    if ~isempty(nC)
                                        voronoi_R = zeros(length(nC),1);
                                        for iN = 1:length(nC)
                                            activity_px = reshaped_stack(idx_candidates(iPx), :);
                                            activity_cluster = pocketTC(pocketList == nC(iN), :);
                                            r = corrcoef(activity_px, activity_cluster); voronoi_R(iN) = r(2);
                                            % Keep track of whether we have performed a
                                            % correction
                                            didCorrect = 1;
                                        end%iN
                                        % Assign new identity
                                        [~, iBest] = max(voronoi_R);
                                        pockets_labeled(idx_candidates(iPx)) = nC(iBest);
                                    end%if isempty(nC)
                                end%iSplitter
                            end% if more
                        end% if not label 0
                    end%iPocket
                    % Check whether there was something to correct.
                    if ~didCorrect
                        break
                    else
                        didCorrect = 0;
                    end%
                end%while
            end%if any

            % -------------------------------------------------------------------------

            % Get each pockets summary statistics
            if nargout > 1
                pocketList = unique(pockets_labeled);
                pocketList = pocketList(pocketList~=0);
                % Preallocation
                summary_stats.pocket_Avg = nan(length(pocketList), 1);
                summary_stats.pocket_Std = nan(length(pocketList), 1);
                summary_stats.pocket_Corr = nan(length(pocketList), 1);
                summary_stats.pocket_Avg_img = nan(size(pockets_labeled));
                summary_stats.pocket_Std_img = nan(size(pockets_labeled));
                summary_stats.pocket_Corr_img = nan(size(pockets_labeled));
                % Iterate over all pockets
                for iPocket = 1:length(pocketList)
                    % Get their index positions
                    idx_pocket = find(pockets_labeled==pocketList(iPocket));
                    % Get avg activity
                    avg = nanmean(nanmean(reshaped_stack(idx_pocket,:)));
                    summary_stats.pocket_Avg(iPocket,1) = avg;
                    summary_stats.pocket_Avg_img(idx_pocket) = avg;
                    % Get std over time
                    sd = nanstd(nanmean(reshaped_stack(idx_pocket,:)));
                    summary_stats.pocket_Std(iPocket,1) = sd;
                    summary_stats.pocket_Std_img(idx_pocket) = sd;
                    % Get within-pocket correlation
                    r = tril(corrcoef(reshaped_stack(idx_pocket, :)'), -1);
                    mask = tril(ones(size(r)), -1);
                    r = mean(r(mask==1));
                    summary_stats.pocket_Corr(iPocket,1) = r;
                    summary_stats.pocket_Corr_img(idx_pocket) = r;
                end%iPocket
            end% if arg

        end%FCN:stackSegmentation

        function idx_candidates_newIdentiy = refinement_parfor(app, idx_candidates, idx_table, pockets_labeled, reshaped_stack, pocketTC, pocketList, nMask)
            % Get current labels and prepare output of new labels
            idx_candidates_Identiy = pockets_labeled(idx_candidates);
            idx_candidates_newIdentiy = idx_candidates_Identiy;
            % Use a parallel for loop to speed things up
            parfor iCan = 1:length(idx_candidates)
                % Get indices of neighbors
                idx_neighbor = conv2( double(idx_table==idx_candidates(iCan)), nMask, 'same')==1;
                % Get neighborhood clusters
                nC = unique(pockets_labeled(idx_neighbor));
                % Check which neighborhood is a better fit
                voronoi_R = zeros(length(nC),1);
                for iN = 1:length(nC)
                    activity_px = reshaped_stack(idx_candidates(iCan), :);
                    activity_cluster = pocketTC(pocketList == nC(iN), :);
                    r = corrcoef(activity_px, activity_cluster); voronoi_R(iN) = r(2);
                end%iN
                % Assign new identity
                [~, iBest] = max(voronoi_R);
                idx_candidates_newIdentiy(iCan) = nC(iBest);
            end%iCan
        end%Fcn:refinement_parfor

        function updatePlots(app)
            if ~isempty(app.stack)
                % First, check whether the 3rd dimension of the stack (i.e. time) is larger
                % than 1
                if size(app.stack,3)==1
                    app.projection_method = 'none';
                end
                % Switch projection methods
                switch app.projection_method
                    case 'std'
                        BG = nanstd(app.stack, [], 3);
                    case 'mean'
                        BG = nanmean(app.stack, 3);
                    case 'max'
                        BG = nanmax(app.stack, [], 3);
                    case 'none'
                        BG = app.stack;
                end%switch
                % Show BG
                cla(app.ax_11)
                imagesc(app.ax_11, BG)
                colormap(app.ax_11, turbo(1000))
                clim(app.ax_11,'auto')
                hold(app.ax_11, "on")
                axis(app.ax_11, 'equal', 'tight')
                set(app.ax_11, 'XTick', [], 'YTick', [])
                title(app.ax_11, [app.projection_method,'-projection'])
                box(app.ax_11, 'on')
                set(app.ax_11, 'Ydir', 'reverse')

                % Switch filter method
                switch app.filter_method
                    case 'raw'
                        regmax_img = imregionalmax(BG,8)>0;
                        regmin_img = imregionalmin(BG,8)>0;
                    case 'filtered'
                        % Filter to combine pockets that belong to the same large scale
                        % maximum/minimum
                        BG_max_filtered = movmax(movmax(BG',3)',3);
                        regmax_img = imregionalmax(BG_max_filtered,8)>0;
                        BG_min_filtered = movmin(movmin(BG',3)',3);
                        regmin_img = imregionalmin(BG_min_filtered,8)>0;
                    case 'both'
                        % Filter to combine pockets that belong to the same large scale
                        % maximum/minimum
                        BG_max_filtered = movmax(movmax(BG',3)',3);
                        regmax_img = (imregionalmax(BG_max_filtered,8) + imregionalmax(BG,8))>0;
                        BG_min_filtered = movmin(movmin(BG',3)',3);
                        regmin_img = (imregionalmin(BG_min_filtered,8) + imregionalmin(BG,8))>0;
                end%switch filter method
                ultimateErosion = bwulterode(regmax_img>0) + bwulterode(regmin_img>0);
                % Kick points that are on the edge of the image. Not this
                % is the opposite during the segmentatino!
                ultimateErosion(1,:) = 0; ultimateErosion(:,1) = 0;
                ultimateErosion(end,:) = 0; ultimateErosion(:,end) = 0;
                [app.locMax_y, app.locMax_x] = find(ultimateErosion);

                % Automatically suggest a minim ROI size based on the
                % number of ROIs
                floor(0.5 * numel(app.stack(:,:,1)) / length(app.locMax_y));
                app.min_ROI_size = floor(numel(app.stack(:,:,1)) / length(app.locMax_y));
                app.minROIsizeEditField.Value = num2str(app.min_ROI_size);

                % Show the corresponding voronoi diagram
                cla(app.ax_12)
                imagesc(app.ax_12, BG)
                hold(app.ax_12, "on")
                plot(app.ax_12, app.locMax_x, app.locMax_y, 'k.')
                dt = delaunayTriangulation([app.locMax_x(:), app.locMax_y(:)]);
                [V,r] = voronoiDiagram(dt);
                % Plot the Voronoi regions
                hWait = waitbar(0, 'Ploting the Voronoi regions ... ');
                for k = 1:length(r)
                    region = r{k};
                    if ~isempty(region)
                        % Get the vertices of the region
                        vertices = V(region, :);
                        % Close the region by adding the first vertex at the end
                        vertices = [vertices; vertices(1, :)];
                        % Plot the region
                        plot(app.ax_12, vertices(:, 1), vertices(:, 2), 'color', [0.25 0.25 0.25], 'LineWidth', 0.5);
                    end%if empty
                end%k
                waitbar(k/length(r), hWait)
                colormap(app.ax_12, turbo(1000))
                clim(app.ax_12,'auto')
                axis(app.ax_12, 'equal', 'tight')
                set(app.ax_12, 'XTick', [], 'YTick', [], 'XLim', [1, size(BG,2)], 'YLim', [1, size(BG,1)])
                title(app.ax_12, 'Voronoi')
                set(app.ax_12, 'Ydir', 'reverse')
                box(app.ax_12, 'on')
                close(hWait)
            end% if stack present

            % Check whether segmentation is available
            if ~isempty(app.pockets_labeled)
                % Show binned image
                cla(app.ax_21)
                imagesc(app.ax_21, app.summary_stats.pocket_Std_img)
                colormap(app.ax_21, turbo(1000))
                clim(app.ax_21,'auto')
                hold(app.ax_21, "on")
                axis(app.ax_21, 'equal', 'tight')
                set(app.ax_21, 'XTick', [], 'YTick', [])
                title(app.ax_21, 'binned std-projection')
                box(app.ax_21, 'on')
                set(app.ax_21, 'Ydir', 'reverse')

                % Show image with borders
                cla(app.ax_22)
                imagesc(app.ax_22, BG)
                hold(app.ax_12, "on")
                % Draw AL outlines
                pocket_list = unique(app.pockets_labeled(:));
                for iP = 1:length(pocket_list)
                    [B, L] = bwboundaries(app.pockets_labeled == pocket_list(iP));
                    for k = 1:length(B)
                        boundary = B{k};
                        plot(app.ax_22, boundary(:, 2), boundary(:, 1), 'color', [0.25 0.25 0.25], 'LineWidth', 0.5)
                    end%k
                end
                % imagesc(app.ax_22, BG,'AlphaData',0.75)
                colormap(app.ax_22, turbo(1000))
                clim(app.ax_22,'auto')
                hold(app.ax_22, "on")
                axis(app.ax_22, 'equal', 'tight')
                set(app.ax_22, 'XTick', [], 'YTick', [])
                title(app.ax_22, [app.projection_method, '-projection with borders'])
                box(app.ax_22, 'on')
                set(app.ax_22, 'Ydir', 'reverse')
            end%if segmentation done

        end%FCN:updatePlots

        function reshape_stack_temporal(app)
            % Apply k-means clustering to compress the third dimension.
            % First, get input
            k = round(str2double(app.curr_zDim.Value),0);
            % Next, check whether it is too large or too small
            if k<2
                k = k;
            elseif k > str2double(app.orig_zDim.Text)
                k = str2double(app.orig_zDim.Text);
            end%if
            % Next, check whether we have to filter
            if str2double(app.gauss_filt_std.Value)>0
                sd = str2double(app.gauss_filt_std.Value);
                for iFrame = 1:size(app.original_stack,3)
                    app.stack(:,:,iFrame) = imgaussfilt(app.original_stack(:,:,iFrame), sd);
                end%iframe
            else
                app.stack = app.original_stack;
            end%if filter
            % Next, apply clustering
            if k ~= size(app.pca_stack,1)
                ID = kmeans(app.pca_stack, k,...
                    'Distance', 'sqeuclidean',...
                    'MaxIter', 1000,...
                    'OnlinePhase', 'on',...
                    'Replicates', 10,...
                    'Options', statset('UseParallel',1));
                % Next, pool layers that belong to the same cluster
                temp_stack = nan(size(app.original_stack,1), size(app.original_stack,2), k);
                for iK = 1:k
                    idx = find(ID==iK);
                    temp_stack(:,:,iK) = mean(app.stack(:,:,idx),3);
                end%iK
                % Overwrite
                app.stack = temp_stack;
            end
            % Update plot
            updatePlots(app)
        end%FCN:reshape_stack_temporal

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.stack_file = [];
            app.original_stack = [];
            app.stack = [];
            app.pca_stack = [];
            app.gauss_filt_std.Value = '0';
            app.projection_method = 'std';
            app.filter_method = 'raw';
            app.n_rep = 0;
            app.min_ROI_size = [];
            app.locMax_y = [];
            app.locMax_x = [];
            app.pockets_labeled = [];
            app.summary_stats = [];
        end

        % Menu selected function: FileMenu
        function FileMenuSelected(app, event)

        end

        % Menu selected function: LoaddataMenu
        function LoaddataMenuSelected(app, event)
            % Change lamp
            app.Lamp.Color = [1 1 0]; pause(0.1)
            % Ask user to select a file
            [file,path] = uigetfile('*.mat', 'Select a stack (x*y*t)');
            app.stack_file = [path, file(1:end-4)];
            % Load the selected file
            load_stack = load([path,file]);
            load_stack_names = fieldnames(load_stack);
            load_stack_names_info = load_stack_names;
            % Add info on dimensions
            for iS = 1:length(load_stack_names)
                d1 = size(load_stack.(load_stack_names{iS}),1);
                d2 = size(load_stack.(load_stack_names{iS}),2);
                d3 = size(load_stack.(load_stack_names{iS}),3);
                load_stack_names_info{iS} = [load_stack_names{iS}, '    (',num2str(d1),'x',num2str(d2),'x',num2str(d3),')'];
            end
            % Ask user to select the stack
            promt_string = {'Select a variable.', 'Select multiple variables with','matching dimensions for','concatenation.'};
            [answer, selected] = listdlg('ListString', load_stack_names_info, 'PromptString',promt_string);
            % Evaluate selection
            if selected
                % Check whether multiple stacks have been selected
                if length(answer)>1
                    % If yes, concatenate them
                    app.stack = [];
                    for iA = 1:length(answer)
                        app.stack = cat(3,app.stack, load_stack.(load_stack_names{answer(iA)}));
                    end%iA
                else
                    % If not, take the single selection
                    app.stack = load_stack.(load_stack_names{answer});
                end
                % Get a PCA-transformed version of the stack
                % First, transform data (rows: frames; cols: all pixels)
                app.pca_stack = nan(size(app.stack,3), size(app.stack,1)*size(app.stack,2));
                for iFrame = 1:size(app.stack,3)
                    temp = app.stack(:,:,iFrame);
                    app.pca_stack(iFrame,:) = temp(:);
                end%iFrame
                % Then, apply PCA and keep 95%
                [~,app.pca_stack,~,~,explained,~] = pca(app.pca_stack, 'Economy', false);
                ind = find(cumsum(explained)>95, 1);
                app.pca_stack = app.pca_stack(:,1:ind);
            else
                app.stack = [];
            end%if selected
            % Keep a copy of the original stack
            app.original_stack = app.stack;
            % Update panel
            app.orig_zDim.Text = ['(',num2str(size(app.original_stack,3)),')'];
            app.curr_zDim.Value = num2str(size(app.original_stack,3));
            % Automatically suggest a minim ROI size
            app.min_ROI_size = floor(sqrt(sqrt(size(app.stack,1)*size(app.stack,2))));
            app.minROIsizeEditField.Value = num2str(app.min_ROI_size);
            % Update plot
            updatePlots(app)
            % Change lamp
            app.Lamp.Color = [0 1 0];
        end

        % Selection changed function: FilteringoflocalextremaButtonGroup
        function FilteringoflocalextremaButtonGroupSelectionChanged(app, event)
            % Change lamp
            app.Lamp.Color = [1 1 0]; pause(0.1)
            selectedButton = app.FilteringoflocalextremaButtonGroup.SelectedObject;
            app.filter_method = selectedButton.Text;
            % Update plots
            updatePlots(app)
            % Change lamp
            app.Lamp.Color = [0 1 0];
        end

        % Selection changed function: ProjectionmethodButtonGroup
        function ProjectionmethodButtonGroupSelectionChanged(app, event)
            % Change lamp
            app.Lamp.Color = [1 1 0]; pause(0.1)
            selectedButton = app.ProjectionmethodButtonGroup.SelectedObject;
            app.projection_method = selectedButton.Text;
            % Update plots
            updatePlots(app)
            % Change lamp
            app.Lamp.Color = [0 1 0];
        end

        % Button down function: ProjectionmethodButtonGroup
        function ProjectionmethodButtonGroupButtonDown(app, event)

        end

        % Button down function: FilteringoflocalextremaButtonGroup
        function FilteringoflocalextremaButtonGroupButtonDown(app, event)

        end

        % Button pushed function: StartsegmentingButton
        function StartsegmentingButtonPushed(app, event)
            % Change lamp
            app.Lamp.Color = [1 1 0]; pause(0.1)
            [app.pockets_labeled, app.summary_stats] = CalciSeg(app, app.stack, app.projection_method, app.filter_method, app.n_rep, app.min_ROI_size);
            % Update plots
            updatePlots(app)
            % Enable saving options
            app.SaveMenu.Enable = 1;
            % Change lamp
            app.Lamp.Color = [0 1 0];
        end

        % Value changed function: minROIsizeEditField
        function minROIsizeEditFieldValueChanged(app, event)
            % Change lamp
            app.Lamp.Color = [1 1 0]; pause(0.1)
            value = app.minROIsizeEditField.Value;
            app.min_ROI_size = str2double(value);
            % Change lamp
            app.Lamp.Color = [0 1 0];
        end

        % Value changed function: RefinementiterationsEditField
        function RefinementiterationsEditFieldValueChanged(app, event)
            % Change lamp
            app.Lamp.Color = [1 1 0]; pause(0.1)
            value = app.RefinementiterationsEditField.Value;
            app.n_rep = value;
            % Change lamp
            app.Lamp.Color = [0 1 0];
        end

        % Value changed function: gauss_filt_std
        function gauss_filt_stdValueChanged(app, event)
            % Change lamp
            app.Lamp.Color = [1 1 0]; pause(0.1)
            % Smooth image using a 2-D Gaussian kernel
            sd = str2double(app.gauss_filt_std.Value);
            if sd > 0
                for iFrame = 1:size(app.original_stack,3)
                    app.stack(:,:,iFrame) = imgaussfilt(app.original_stack(:,:,iFrame), sd);
                end
            elseif sd==0
                app.stack = app.original_stack;
            else
                app.stack = app.original_stack;
                app.gauss_filt_std.Value = '0';
            end%if
            % Get a PCA-transformed version of the stack
            % First, transform data (rows: frames; cols: all pixels)
            app.pca_stack = nan(size(app.stack,3), size(app.stack,1)*size(app.stack,2));
            for iFrame = 1:size(app.stack,3)
                temp = app.stack(:,:,iFrame);
                app.pca_stack(iFrame,:) = temp(:);
            end%iFrame
            % Then, apply PCA and keep 95%
            [~,app.pca_stack,~,~,explained,~] = pca(app.pca_stack, 'Economy', false);
            ind = find(cumsum(explained)>95, 1);
            app.pca_stack = app.pca_stack(:,1:ind);
            % Compress the third dimension using the filtered stack
            reshape_stack_temporal(app)
            % Change lamp
            app.Lamp.Color = [0 1 0];
        end

        % Value changed function: curr_zDim
        function curr_zDimValueChanged(app, event)
            % Change lamp
            app.Lamp.Color = [1 1 0]; pause(0.1)
            % Use k-means clustering to compress the third dimension.
            reshape_stack_temporal(app)
            % Change lamp
            app.Lamp.Color = [0 1 0];
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            % ASk user where to put the file
            [file,path] = uiputfile('*.mat','Save segmentation', [app.stack_file,'_segmentation.mat']);
            % Get variables to save
            % --- Segmentation
            Segmentation.pockets_labeled = app.pockets_labeled;
            Segmentation.summary_stats = app.summary_stats;
            % --- Info on segmentation
            Segmentation.info.gauss_filt = app.gauss_filt_std.Value;
            Segmentation.info.zDim = app.curr_zDim.Value;
            Segmentation.info.projection_method = app.projection_method;
            Segmentation.info.max_filter = app.filter_method;
            Segmentation.info.nRep = app.n_rep;
            Segmentation.info.min_size = app.min_ROI_size;
            % Apply Otsu's method to define active ROIs
            Segmentation.summary_stats.pocket_active = imbinarize(Segmentation.summary_stats.pocket_Std_img);
            % Ask user to select their own set of active pockets
            % ---- coming soon ---
            % Save
            save([path,file], 'Segmentation')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenuSelected, true);
            app.FileMenu.Text = 'File';
            app.LoaddataMenu = uimenu(app.FileMenu);
            app.LoaddataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoaddataMenuSelected, true);
            app.LoaddataMenu.Text = 'Load data';
            app.SaveMenu = uimenu(app.UIFigure);
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.SaveMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as';
            app.ax_11 = uiaxes(app.UIFigure);
            app.ax_11.Tag = 'ax_11';
            app.ax_21 = uiaxes(app.UIFigure);
            app.ax_21.Tag = 'ax_main21';
            app.ax_12 = uiaxes(app.UIFigure);
            app.ax_12.Tag = 'ax_12';
            app.ax_22 = uiaxes(app.UIFigure);
            app.ax_22.Tag = 'ax_22';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Tag = 'lp_status';
            app.StartsegmentingButton = uibutton(app.UIFigure, 'push');
            app.StartsegmentingButton.ButtonPushedFcn = createCallbackFcn(app, @StartsegmentingButtonPushed, true);
            app.StartsegmentingButton.Tag = 'bt_start';
            app.StartsegmentingButton.Text = 'Start segmenting';
            app.FilteringoflocalextremaButtonGroup = uibuttongroup(app.UIFigure);
            app.FilteringoflocalextremaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilteringoflocalextremaButtonGroupSelectionChanged, true);
            app.FilteringoflocalextremaButtonGroup.Title = 'Filtering of local extrema';
            app.FilteringoflocalextremaButtonGroup.ButtonDownFcn = createCallbackFcn(app, @FilteringoflocalextremaButtonGroupButtonDown, true);
            app.FilteringoflocalextremaButtonGroup.Tag = 'rb_filtering';
            app.rawButton = uiradiobutton(app.FilteringoflocalextremaButtonGroup);
            app.rawButton.Text = 'raw';
            app.rawButton.Value = true;
            app.filteredButton = uiradiobutton(app.FilteringoflocalextremaButtonGroup);
            app.filteredButton.Text = 'filtered';
            app.bothButton = uiradiobutton(app.FilteringoflocalextremaButtonGroup);
            app.bothButton.Text = 'both';
            app.RefinementiterationsEditFieldLabel = uilabel(app.UIFigure);
            app.RefinementiterationsEditFieldLabel.Text = 'Refinement iterations';
            app.RefinementiterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RefinementiterationsEditField.ValueChangedFcn = createCallbackFcn(app, @RefinementiterationsEditFieldValueChanged, true);
            app.RefinementiterationsEditField.Tag = 'ed_iterations';
            app.ProjectionmethodButtonGroup = uibuttongroup(app.UIFigure);
            app.ProjectionmethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProjectionmethodButtonGroupSelectionChanged, true);
            app.ProjectionmethodButtonGroup.Title = 'Projection method';
            app.ProjectionmethodButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ProjectionmethodButtonGroupButtonDown, true);
            app.ProjectionmethodButtonGroup.Tag = 'rb_projection_method';
            app.stdButton_2 = uiradiobutton(app.ProjectionmethodButtonGroup);
            app.stdButton_2.Text = 'std';
            app.stdButton_2.Value = true;
            app.meanButton = uiradiobutton(app.ProjectionmethodButtonGroup);
            app.meanButton.Text = 'mean';
            app.maxButton = uiradiobutton(app.ProjectionmethodButtonGroup);
            app.maxButton.Text = 'max';
            app.CalciSegLabel_2 = uilabel(app.UIFigure);
            app.CalciSegLabel_2.Tag = 'tx_subtitle_1';
            app.CalciSegLabel_2.Text = 'A versatile approach for unsupervised';
            app.CalciSegLabel_3 = uilabel(app.UIFigure);
            app.CalciSegLabel_3.Tag = 'tx_subtitle_1';
            app.CalciSegLabel_3.Text = 'segmentation of calcium imaging data';
            app.minROIsizeEditFieldLabel = uilabel(app.UIFigure);
            app.minROIsizeEditFieldLabel.Text = 'min. ROI size';
            app.minROIsizeEditField = uieditfield(app.UIFigure, 'text');
            app.minROIsizeEditField.ValueChangedFcn = createCallbackFcn(app, @minROIsizeEditFieldValueChanged, true);
            app.minROIsizeEditField.Tag = 'ed_minROIsize';
            app.minROIsizeEditField.Value = '0';
            app.StackpreprocessingButtonGroup = uibuttongroup(app.UIFigure);
            app.StackpreprocessingButtonGroup.Title = 'Stack pre-processing';
            app.StackpreprocessingButtonGroup.Tag = 'rb_projection_method';
            app.sdLabel = uilabel(app.StackpreprocessingButtonGroup);
            app.sdLabel.Text = 'sd';
            app.gauss_filt_std = uieditfield(app.StackpreprocessingButtonGroup, 'text');
            app.gauss_filt_std.ValueChangedFcn = createCallbackFcn(app, @gauss_filt_stdValueChanged, true);
            app.gauss_filt_std.Value = '####';
            app.zEditFieldLabel = uilabel(app.StackpreprocessingButtonGroup);
            app.zEditFieldLabel.Text = 'z';
            app.curr_zDim = uieditfield(app.StackpreprocessingButtonGroup, 'text');
            app.curr_zDim.ValueChangedFcn = createCallbackFcn(app, @curr_zDimValueChanged, true);
            app.curr_zDim.Value = '####';
            app.gauss_filt = uilabel(app.StackpreprocessingButtonGroup);
            app.gauss_filt.Text = 'Gauss. filter';
            app.orig_zDim = uilabel(app.StackpreprocessingButtonGroup);
            app.orig_zDim.Text = '(####)';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'CalciSeg_logo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalciSeg_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end